classdef new_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DataTree                    matlab.ui.container.Tree
        ProcessinglevelButtonGroup  matlab.ui.container.ButtonGroup
        SessionButton               matlab.ui.control.RadioButton
        RunButton                   matlab.ui.control.RadioButton
        SubjButton                  matlab.ui.control.RadioButton
        GroupButton                 matlab.ui.control.RadioButton
        SignalListBox               matlab.ui.control.ListBox
        PlotButton                  matlab.ui.control.Button
        ChannelDropDown             matlab.ui.control.DropDown
        ChannelDropDownLabel        matlab.ui.control.Label
        PlotTypeButtonGroup         matlab.ui.container.ButtonGroup
        ConcButton                  matlab.ui.control.RadioButton
        ODButton                    matlab.ui.control.RadioButton
        RawdataButton               matlab.ui.control.RadioButton
        EditOptionButton            matlab.ui.control.Button
        ListBoxData                 matlab.ui.control.ListBox
        DatafilesLabel              matlab.ui.control.Label
        RunImageReconExp            matlab.ui.control.Button
        BrowseData                  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    
    properties (Access = private)
        Property % Description
        AdvApp
        ds % data source
        cache % current process
        sel % user selection
        cfg
    end
    
    methods (Access = private)


        %% This function update the "Signal List" box on the UI
        % It check which plot type the user selected Raw/OD/Conc.
        % e.g: user select Conc -> it show (like Hbo/HbR/HbT)
        % After creating this list, the function updates the SignalListBox on the UI 
        function updateSignalList(app)
            plotType = app.PlotTypeButtonGroup.SelectedObject.Text;
            firstRun = app.ds.groupResult.subjs(1).sess(1).runs(1);
            firstRun.Load();
            app.ds.waveLength = firstRun.acquired.probe.wavelengths;
            items = {};

            % user select Raw/OD -> it will show (like 670nm, 850nm)
            if strcmp(plotType, 'Raw data') || strcmp(plotType, 'OD')
                for i = 1:numel(app.ds.waveLength)
                    items{i} = sprintf('%dnm', round(app.ds.waveLength(i)));
                end
                
            % user select Conc -> it show (like Hbo/HbR/HbT)
            elseif strcmp(plotType, 'Conc')
                items{1} = 'HbO';
                items{2} = 'HbR';
                items{3} = 'HbT';
            end

            % update Signal List Box
            app.SignalListBox.Items = items;
            app.SignalListBox.Value = items{1};
            drawnow;
        end
        

        %%  Parse channel label text "TxX–RxY" into numeric ID
        function [s,d] = parseChannelLabel(~,label)
            % extract 2 numbers: after 'Tx' and after 'Rx', with any non-digit separator between
            tok = regexp(label,'Tx(\d+)[^\d]+Rx(\d+)','tokens','once');
            s = str2double(tok{1});
            d = str2double(tok{2});
        end


        %% Parse the integer number before 'nm'
        function waveLengthIdx = nmLabelToIndex(app, nmLabel)
            waveLengthIdx = [];
            nm = sscanf(nmLabel, '%dnm');
            if isempty(nm) || isempty(app.ds.wls)
                waveLengthIdx = [];
                return;
            end
            % Find the first index where wavelength equals that number
            waveLengthIdx = find(app.ds.wls == nm, 1);
        end
   

        %%
        function getChannelList(app)
            % I pick first rn to get channel list
            firstRun = app.ds.groupResult.subjs(1).sess(1).runs(1);
            firstRun.Load();
            assignin('base','firstRun',firstRun);
            test = firstRun.acquired;
            assignin('base', 'test', test);
            ml = firstRun.acquired.data.measurementList;

            if isempty(ml)
                uialert(app.UIFigure, 'measurementList is empty.', 'Error');
                return;
            end

            sourceArray = zeros(numel(ml), 1);
            detectorArray = zeros(numel(ml), 1);

            %fill the arrays
            for i = 1:(numel(ml))
                sourceArray(i) = ml(i).sourceIndex;
                detectorArray(i) = ml(i).detectorIndex;
            end

            % Combine Tx and Rx into one nx2 array
            % make a list of UNIQUE [Tx,Rx] pairs
            % keep only the first occurrence of each pair
            pairsRaw = [sourceArray detectorArray];
            pairs = []; % start with empty list

            for i = 1:size(pairsRaw, 1)
                isExist = false;
                row = pairsRaw(i,:);

                % check if this pair is already in 'pairs'
                for j = 1:size(pairs, 1)
                    if all(row == pairs(j,:))
                        isExist = true;
                        break;
                    end 
                end

                % If the pair is new, add it toe the list
                if isExist == false
                    pairs = [pairs; row];
                end
            end


            % create label and map, update channel to ListBox of channel
            rowSize = size(pairs, 1);
            channelMap(rowSize, 1) = struct('label', "", 'source', 0, 'detector', 0);

            for i = 1:rowSize
                source = pairs(i, 1);
                detector = pairs(i, 2);

                label = sprintf('Tx%d-Rx%d', source, detector);

                channelMap(i).label = label;
                channelMap(i).source = source;
                channelMap(i).detector = detector;

                %fprintf('Channel %d: s=%d, d=%d, label=%s\n', i, source, detector, label);
            end


            % update channelDropDown on GUI
            items = {channelMap.label}.';
            app.ChannelDropDown.Items = items;
            app.ChannelDropDown.Value = app.ChannelDropDown.Items{1};
            drawnow;

            % i need this one for find wavelength INDEX
            app.ds.wls = firstRun.GetWls();
        end

        
        %%
        function rebasePath(~, root, group)
            parentPath = filesepStandard(root, 'dir');
            
            for i = 1:numel(group.subjs)
                group.subjs(i).path = parentPath;
                for j = 1:numel(group.subjs(i).sess)
                    group.subjs(i).sess(j).path = parentPath;
                    for k = 1:numel(group.subjs(i).sess(j).runs)
                        group.subjs(i).sess(j).runs(k).path = parentPath;
                    end
                end
            end     
        end


        function buildTreeFromGroup(app, group)
            ig = max(1, group.iGroup);
        
            groupNode = uitreenode(app.DataTree, ...
                "Text", char(group.GetOutputFilename()), ...
                "NodeData", struct('type','group','idx',[ig 0 0 0]));
        
            for isubj = 1:numel(group.subjs)
                subj = group.subjs(isubj);
                subjNode = uitreenode(groupNode, ...
                    "Text", char(subj.GetOutputFilename()), ...
                    "NodeData", struct('type','subj','idx',[ig isubj 0 0]));
        
                for isess = 1:numel(subj.sess)
                    sess = subj.sess(isess);
                    sessNode = uitreenode(subjNode, ...
                        "Text", char(sess.GetOutputFilename()), ...
                        "NodeData", struct('type','sess','idx',[ig isubj isess 0]));
        
                    for irun = 1:numel(sess.runs)
                        run = sess.runs(irun);
                        uitreenode(sessNode, ...
                            "Text", char(run.GetOutputFilename()), ...
                            "NodeData", struct('type','run','idx',[ig isubj isess irun]));
                    end
                    expand(sessNode);
                end
                expand(subjNode);
            end
            expand(groupNode);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % DEFAULT DEFAULT
            appDir  = fileparts(mfilename('fullpath')); % .../SSDOT-main/GUI
            rootDir = fileparts(appDir); % .../SSDOT-main
        

            addpath(genpath(fullfile(rootDir,'code')));
            addpath(genpath(fullfile(rootDir,'homer_function')));
            % addpath(genpath(fullfile(rootDir,'homer_class')));
            

            % DEFAULT VAR, user can change these parameter on GUI

            % SYNC UI

            % Other
    
        end

        % Button pushed function: BrowseData
        function BrowseDataButtonPushed(app, event)
            %% Open a folder selection to allow user to choose(browse) the data folder
            %% Then after select will immediately load GroupResults.m data and show the subjects/group/run list
            
            % let user pick Homer output folder eg: ST001 folder
            folderPath = uigetdir(pwd, 'Select the output homer folder');

            if isequal(folderPath,0)
                return; % user hit cancel
            end

            % ST001/derivatives/homer/groupResults.mat
            groupPath = fullfile(folderPath, 'derivatives', 'homer', 'groupResults.mat');
            S = load(groupPath, 'group');
            app.ds.groupResult = S.group;

            % After you load groupResults.mat, reset all run/session paths to your current folder before calling
            rebasePath(app, folderPath, app.ds.groupResult);

            buildTreeFromGroup(app, app.ds.groupResult);


            getChannelList(app);
            updateSignalList(app);


        end

        % Selection changed function: PlotTypeButtonGroup
        function PlotTypeButtonGroupSelectionChanged(app, event)
            updateSignalList(app);
            
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)

            
        end

        % Clicked callback: SignalListBox
        function SignalListBoxClicked(app, event)
            

        end

        % Selection changed function: ProcessinglevelButtonGroup
        function ProcessinglevelButtonGroupSelectionChanged(app, event)
            app.sel.processingLevel = app.ProcessinglevelButtonGroup.SelectedObject.Text;
            app.DataTree.SelectedNodes =[];

            
        end

        % Clicked callback: DataTree
        function DataTreeClicked(app, event)
            node = event.InteractionInformation.Node;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            %% Wrap everything to check before hit plot button
            %% Basic guards

            
            % Require a run selection: app.sel = [iGroup iSubj iSess iRun]
            if isempty(app.sel)
                uialert(app.UIFigure,'Select a RUN in the tree.','Info');
                return;
            end

            % Map selection to the run object
            idx = app.sel;
            run = app.ds.groupResult.subjs(idx(2)).sess(idx(3)).runs(idx(4));
            run.Load();
            
            run = run.procStream.output;

            [source, detector] = app.parseChannelLabel(app.ChannelDropDown.Value);
            selectSignal = app.SignalListBox.Value; % this one can we wavelength or Hbo Hbr Hbt

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            legendLabels = {};


            switch app.ProcessinglevelButtonGroup.SelectedObject.Text
                case 'Run'
                    switch app.PlotTypeButtonGroup.SelectedObject.Text
                        case 'Raw data'
                        case 'OD'
                            waveLengthIdx = app.nmLabelToIndex(selectSignal);
                            ml = run.dod.measurementList;

                            sourceArrayOD = zeros(numel(ml), 1);
                            detectorArrayOD = zeros(numel(ml), 1);
                            waveLengthArrayOD = zeros(numel(ml), 1);


                            %fill the arrays
                            for i = 1:(numel(ml))
                                sourceArrayOD(i) = ml(i).sourceIndex;
                                detectorArrayOD(i) = ml(i).detectorIndex;
                                waveLengthArrayOD(i) = ml(i).wavelengthIndex;
                            end

                            col = find(source == sourceArrayOD & ...
                                detector == detectorArrayOD &  ...
                                waveLengthIdx == waveLengthArrayOD, 1, 'first');

                            plot(app.UIAxes, run.dod.time, run.dod.dataTimeSeries(:,col), 'LineWidth', 1.6);
                            legendLabels{end + 1} = sprintf('Tx%d-Rx%d %s', source, detector, selectSignal);
                            xlabel(app.UIAxes,'Time (s)');
                            ylabel(app.UIAxes,'Intensity (a.u.)');
                            title(app.UIAxes, sprintf('Optical Density — Tx%d–Rx%d', source, detector));
                            legend(app.UIAxes, legendLabels,'Location','northeast');
                            grid(app.UIAxes,'on');
                            hold(app.UIAxes,'off');

                        case 'Conc'
                            

                    end

            end


        end

        % Selection changed function: DataTree
        function DataTreeSelectionChanged(app, event)
            % --- 1) Get the selected node (doc: event.SelectedNodes) ---
            node = event.SelectedNodes;
            if isempty(node); return; end          % nothing selected
            node = node(1);                        % guard even if Multiselect accidentally 'on'
        
            % --- 2) Read its NodeData (must be struct) ---
            nd = node.NodeData;
            if ~isstruct(nd) || ~isfield(nd,'type') || ~isfield(nd,'idx')
                % If your tree was just rebuilt, or a legacy node slipped in:
                app.DataTree.SelectedNodes = matlab.ui.container.TreeNode.empty;  % doc: empty allowed
                app.sel = [];
                return;
            end
            nodeType = nd.type;
        
            % --- 3) Map the radio buttons to desired level ---
            txt = lower(strtrim(app.ProcessinglevelButtonGroup.SelectedObject.Text));  % 'run'/'session'/...
            switch txt
                case 'run',     want = 'run';
                case 'session', want = 'sess';
                case 'subj',    want = 'subj';
                case 'group',   want = 'group';
                otherwise,      want = 'run';
            end
        
            % --- 4) Enforce: if mismatch, clear selection (per doc rules) ---
            if ~strcmp(nodeType, want)
            % Wrong level → clear selection properly
                app.DataTree.SelectedNodes = [];
                uialert(app.UIFigure, "Wrong level file", "Error");
                return;
            end
        
            % --- 5) Good selection -> remember the index for later plotting ---
            app.sel = nd.idx;   % [igroup isubj isess irun]

        end

        % Button pushed function: EditOptionButton
        function EditOptionButtonPushed(app, event)
            % Open the advanced settings window
            % allow user change some parameters
            % Disable the button while the dialog is open
            app.EditOptionButton.Enable = "off";
        
            % Open Advanced settings dialog
            app.AdvApp = AdvancedSetting(app.cfg, @app.onAdvancedApply, @app.onAdvancedClose);
            app.AdvApp.UIFigure.WindowStyle = 'modal';
        
            % Ensure the button is re-enabled if user closes the window via [X]
            %app.AdvApp.UIFigure.CloseRequestFcn = @(src,evt) app.onAdvClosed();

        end

        % Button pushed function: RunImageReconExp
        function RunImageReconExpButtonPushed(app, event)
            %% nothing...
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.BrowseData = uibutton(app.UIFigure, 'push');
            app.BrowseData.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataButtonPushed, true);
            app.BrowseData.Text = 'Browse';
            app.RunImageReconExp = uibutton(app.UIFigure, 'push');
            app.RunImageReconExp.ButtonPushedFcn = createCallbackFcn(app, @RunImageReconExpButtonPushed, true);
            app.RunImageReconExp.Text = 'Run reconstruct';
            app.DatafilesLabel = uilabel(app.UIFigure);
            app.DatafilesLabel.Text = 'Data';
            app.ListBoxData = uilistbox(app.UIFigure);
            app.ListBoxData.Items = {};
            app.ListBoxData.Multiselect = 'on';
            app.ListBoxData.Value = {};
            app.EditOptionButton = uibutton(app.UIFigure, 'push');
            app.EditOptionButton.ButtonPushedFcn = createCallbackFcn(app, @EditOptionButtonPushed, true);
            app.EditOptionButton.Text = 'Edit Option';
            app.PlotTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotTypeButtonGroupSelectionChanged, true);
            app.PlotTypeButtonGroup.Title = 'Plot Type';
            app.RawdataButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.RawdataButton.Text = 'Raw data';
            app.RawdataButton.Value = true;
            app.ODButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ODButton.Text = 'OD';
            app.ConcButton = uiradiobutton(app.PlotTypeButtonGroup);
            app.ConcButton.Text = 'Conc';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = {};
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.SignalListBox = uilistbox(app.UIFigure);
            app.SignalListBox.Items = {};
            app.SignalListBox.ClickedFcn = createCallbackFcn(app, @SignalListBoxClicked, true);
            app.SignalListBox.Value = {};
            app.ProcessinglevelButtonGroup = uibuttongroup(app.UIFigure);
            app.ProcessinglevelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProcessinglevelButtonGroupSelectionChanged, true);
            app.ProcessinglevelButtonGroup.Title = 'Processing level';
            app.GroupButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.GroupButton.Text = 'Group';
            app.SubjButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SubjButton.Text = 'Subj';
            app.RunButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.RunButton.Text = 'Run';
            app.RunButton.Value = true;
            app.SessionButton = uiradiobutton(app.ProcessinglevelButtonGroup);
            app.SessionButton.Text = 'Session';
            app.DataTree = uitree(app.UIFigure);
            app.DataTree.SelectionChangedFcn = createCallbackFcn(app, @DataTreeSelectionChanged, true);
            app.DataTree.ClickedFcn = createCallbackFcn(app, @DataTreeClicked, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = new_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end